classdef carcontrol < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        MiscPanel                    matlab.ui.container.Panel
        StopButton                   matlab.ui.control.Button
        voltageLabel                 matlab.ui.control.Label
        UpdateVoltageButton          matlab.ui.control.Button
        BatteryVoltageLabel          matlab.ui.control.Label
        BeepButton                   matlab.ui.control.Button
        ModesPanel                   matlab.ui.container.Panel
        DriveUntilLineButton         matlab.ui.control.Button
        DriveUntilWallButton         matlab.ui.control.Button
        SensorStreamingPanel         matlab.ui.container.Panel
        TimestampLabel               matlab.ui.control.Label
        RightLamp                    matlab.ui.control.Lamp
        RightLampLabel               matlab.ui.control.Label
        MiddleLamp                   matlab.ui.control.Lamp
        CenterLampLabel              matlab.ui.control.Label
        LeftLamp                     matlab.ui.control.Lamp
        LeftLampLabel                matlab.ui.control.Label
        StreamingSensorValuesSwitch  matlab.ui.control.Switch
        SwitchLabel                  matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        SpeedSlider                  matlab.ui.control.Slider
        SpeedSliderLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        mqClient % Description
        priorKey = ''
    end

    methods (Access = private)

        function myCallback(app, varargin)
            % Unpack topic and message safely from varargin
            topic = varargin{1};
            message = varargin{2};
            
            fprintf("Received: %s\n", message);
            messageStruct = jsondecode(message);
            messageType = messageStruct.type;
            
            if strcmp(messageType, "set_speeds")
                messagePayload = messageStruct.payload;
            end
            
            if strcmp(messageType, "line_sensor_reading")
                messagePayload = messageStruct.payload;
                
                app.TimestampLabel.Text = messagePayload.timestamp;
                if strcmp(messagePayload.left, "B")
                    app.LeftLamp.Color = 'green';
                else
                    app.LeftLamp.Color = 'black';
                end
                if strcmp(messagePayload.middle, "B")
                    app.MiddleLamp.Color = 'green';
                else
                    app.MiddleLamp.Color = 'black';
                end
                if strcmp(messagePayload.right, "B")
                    app.RightLamp.Color = 'green';
                else
                    app.RightLamp.Color = 'black';
                end
            end

            if strcmp(messageType, "voltage_reading")
                messagePayload = messageStruct.payload;
                app.voltageLabel.Text = num2str(messagePayload.voltage);
            end
        end

        function sendMessage(app, messageType, messagePayload)
            messageStuct.type = messageType;
            if nargin > 2
                messageStuct.payload = messagePayload;
            end
            messageJson = jsonencode(messageStuct)

            write(app.mqClient, "me435/schmitjx/to_pi", messageJson)
            fprintf("Sent: %s\n", messageJson)
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.mqClient = mqttclient("tcp://mosquitto.csse.rose-hulman.edu");
            app.mqClient.subscribe("me435/schmitjx/#", "Callback", @app.myCallback)

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(key, app.priorKey)
                return %the key is being held down ignore this one.
            end
            app.priorKey = key;

            speed = round(app.SpeedSlider.Value);
            if strcmp(key, "uparrow")
                app.sendMessage("set_speeds", [speed speed speed speed])
            end
            if strcmp(key, "downarrow")
                app.sendMessage("set_speeds", [-speed -speed -speed -speed])
            end
            if strcmp(key, "leftarrow")
                app.sendMessage("set_speeds", [-speed speed speed -speed])
            end
            if strcmp(key, "rightarrow")
                app.sendMessage("set_speeds", [speed -speed -speed speed])
            end
            if strcmp(key, "a")
                app.sendMessage("set_speeds", [-speed -speed speed speed])
            end
            if strcmp(key, "b")
                app.sendMessage("set_speeds", [speed speed -speed -speed])
            end
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            app.priorKey = "";
            app.sendMessage("stop")

        end

        % Button pushed function: DriveUntilWallButton
        function DriveUntilWallButtonPushed(app, event)
            app.sendMessage("mode", "drive_until_wall");
        end

        % Button pushed function: DriveUntilLineButton
        function DriveUntilLineButtonPushed(app, event)
            app.sendMessage("mode", "drive_until_line");
        end

        % Value changed function: StreamingSensorValuesSwitch
        function StreamingSensorValuesSwitchValueChanged(app, event)
            value = app.StreamingSensorValuesSwitch.Value;
            if strcmpi(value,"on")
                app.sendMessage("is_streaming", true)
            else
                app.sendMessage("is_streaming", false)
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.sendMessage("mode", "none")
        end

        % Button pushed function: BeepButton
        function BeepButtonPushed(app, event)
            app.sendMessage("beep",[])
        end

        % Button pushed function: UpdateVoltageButton
        function UpdateVoltageButtonPushed(app, event)
            app.sendMessage("get_voltage");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.SpeedSliderLabel = uilabel(app.UIFigure);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.UIFigure);
            app.SpeedSlider.Value = 40;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Click in the area below to use the keyboard.';
            app.SensorStreamingPanel = uipanel(app.UIFigure);
            app.SensorStreamingPanel.Title = 'Sensor Streaming';
            app.SwitchLabel = uilabel(app.SensorStreamingPanel);
            app.SwitchLabel.Text = 'Streaming Sensor Values';
            app.StreamingSensorValuesSwitch = uiswitch(app.SensorStreamingPanel, 'slider');
            app.StreamingSensorValuesSwitch.ValueChangedFcn = createCallbackFcn(app, @StreamingSensorValuesSwitchValueChanged, true);
            app.LeftLampLabel = uilabel(app.SensorStreamingPanel);
            app.LeftLampLabel.Text = 'Left';
            app.LeftLamp = uilamp(app.SensorStreamingPanel);
            app.CenterLampLabel = uilabel(app.SensorStreamingPanel);
            app.CenterLampLabel.Text = 'Center';
            app.MiddleLamp = uilamp(app.SensorStreamingPanel);
            app.RightLampLabel = uilabel(app.SensorStreamingPanel);
            app.RightLampLabel.Text = 'Right';
            app.RightLamp = uilamp(app.SensorStreamingPanel);
            app.TimestampLabel = uilabel(app.SensorStreamingPanel);
            app.TimestampLabel.Text = 'Timestamp';
            app.ModesPanel = uipanel(app.UIFigure);
            app.ModesPanel.Title = 'Modes';
            app.DriveUntilWallButton = uibutton(app.ModesPanel, 'push');
            app.DriveUntilWallButton.ButtonPushedFcn = createCallbackFcn(app, @DriveUntilWallButtonPushed, true);
            app.DriveUntilWallButton.Text = 'Drive Until Wall';
            app.DriveUntilLineButton = uibutton(app.ModesPanel, 'push');
            app.DriveUntilLineButton.ButtonPushedFcn = createCallbackFcn(app, @DriveUntilLineButtonPushed, true);
            app.DriveUntilLineButton.Text = 'Drive Until Line';
            app.MiscPanel = uipanel(app.UIFigure);
            app.MiscPanel.Title = 'Misc.';
            app.BeepButton = uibutton(app.MiscPanel, 'push');
            app.BeepButton.ButtonPushedFcn = createCallbackFcn(app, @BeepButtonPushed, true);
            app.BeepButton.Text = 'Beep';
            app.BatteryVoltageLabel = uilabel(app.MiscPanel);
            app.BatteryVoltageLabel.Text = 'Battery Voltage:';
            app.UpdateVoltageButton = uibutton(app.MiscPanel, 'push');
            app.UpdateVoltageButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateVoltageButtonPushed, true);
            app.UpdateVoltageButton.Text = 'Update Voltage';
            app.voltageLabel = uilabel(app.MiscPanel);
            app.voltageLabel.Text = 'voltage';
            app.StopButton = uibutton(app.MiscPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = carcontrol

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end